classdef gui_app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ToolsMenu                     matlab.ui.container.Menu
        UploadImageMenu               matlab.ui.container.Menu
        ScreenshotMenu                matlab.ui.container.Menu
        ModeSwitchMenu                matlab.ui.container.Menu
        BeginnerMenu                  matlab.ui.container.Menu
        ExpertMenu                    matlab.ui.container.Menu
        ResetMenu                     matlab.ui.container.Menu
        Toolbar                       matlab.ui.container.Toolbar
        PushTool2                     matlab.ui.container.toolbar.PushTool
        PushTool                      matlab.ui.container.toolbar.PushTool
        PushTool3                     matlab.ui.container.toolbar.PushTool
        PushTool4                     matlab.ui.container.toolbar.PushTool
        PushTool5                     matlab.ui.container.toolbar.PushTool
        PushTool6                     matlab.ui.container.toolbar.PushTool
        NumberofForegroundEditField   matlab.ui.control.NumericEditField
        NumberofForegroundLabel       matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        GUIInstructionsTextArea       matlab.ui.control.TextArea
        GUIInstructionsTextAreaLabel  matlab.ui.control.Label
        ModeSwitchButton              matlab.ui.control.StateButton
        TippsTextArea                 matlab.ui.control.TextArea
        TippsTextAreaLabel            matlab.ui.control.Label
        StepsPanel                    matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        Step6ScreenshotButton         matlab.ui.control.Button
        Step5AnimationButton_2        matlab.ui.control.Button
        Step43DModelButton            matlab.ui.control.Button
        Step3SelectionButton          matlab.ui.control.Button
        Step2ForegroundButton         matlab.ui.control.Button
        Step1LoadPictureButton        matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        ResetMenu_2                   matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        ResetMenu_3                   matlab.ui.container.Menu
        ContextMenu3                  matlab.ui.container.ContextMenu
        ResetMenu_4                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        picture; % Description
        points % Description
        picture2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModeSwitchButton
        function ModeSwitchButtonValueChanged2(app, event)
            value = app.ModeSwitchButton.Value;
            switch value
                case 0             
                    app.StepsPanel.Visible ="on";
                    app.TippsTextArea.Visible ="on";
                    app.TippsTextAreaLabel.Visible ="on";
                    app.Toolbar.Visible="off";
                    app.UIAxes.Position = [229 109 929 564];
                case 1
                    app.StepsPanel.Visible="off";
                    app.TippsTextArea.Visible ="off";
                    app.TippsTextAreaLabel.Visible="off";
                    app.Toolbar.Visible="on";
                    app.UIAxes.Position = [17 61 1141 612];
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.GUIInstructionsTextArea.Visible = "off";
            app.GUIInstructionsTextAreaLabel.Visible = "off";
            app.OKButton.Visible="off";
            app.TippsTextArea.Value=["Now pay attention to the left side about Steps List."
                      "" 
            "Firstly, click the Step1 Button to choose the picture that you want deal with."
                      ];
        end

        % Button pushed function: Step1LoadPictureButton
        function Step1LoadPictureButtonPushed(app, event)
            app.picture=[];
            f=[];
            p=[];
            [f ,p]= uigetfile({'*.jpg;*.png','Image Files'});
            fname =[p f];
            app.picture = imread(fname);
            
            imshow(app.picture,'Parent',app.UIAxes);
            app.TippsTextArea.Value=[
                      "Then if there are any Foreground need to choose, fill the number on the right side and click the Step 2 Button."
                      "" 
            "If not, click the Step 3 Button directly to select the Vanish Point and Background of the picture."
            "" 
            "If you want to change the uploaded image, right click the Step 1 Button to reset this step and left click this Button to select new picture."
                      ];

           app.NumberofForegroundEditField.Visible="on";
           app.NumberofForegroundLabel.Visible="on";
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            app.picture=[];
            f=[];
            p=[];
            [f ,p]= uigetfile({'*.jpg;*.png','Image Files'});
            fname =[p f];
            app.picture = imread(fname);
            
            imshow(app.picture,'Parent',app.UIAxes);
            app.NumberofForegroundEditField.Visible="on";
            app.NumberofForegroundLabel.Visible="on";
        end

        % Menu selected function: UploadImageMenu
        function UploadImageMenuSelected(app, event)
            app.picture=[];
            f=[];
            p=[];
            [f, p]= uigetfile({'*.jpg;*.png','Image Files'});
            fname =[p f];
            app.picture = imread(fname);
           
            imshow(app.picture,'Parent',app.UIAxes);
        end

        % Button pushed function: Step3SelectionButton
        function Step3SelectionButtonPushed(app, event)
            app.NumberofForegroundLabel.Visible="off";
            app.NumberofForegroundEditField.Visible="off";
            
            app.TippsTextArea.Value=["Now it's the Selection process."
                      "" 
            "Use the mouse to move the vanish point and double click to confirm, also for the background."
                    "" 
            "The blue lines show the five region of the picture.  Then click the Step 4 Button to show the 3D Model."  
            "" 
            "If you're not satisfied with result, right click the Step 3 Button to reset this step and left click this Button to repeat again."];
           
            % app.points = Selection(app.picture,app.UIAxes);

            if isempty(app.picture2)
                app.points = Selection(app.picture,app.UIAxes);

            else
                 app.points = Selection(app.picture2,app.UIAxes);

            end
        end

        % Button pushed function: Step2ForegroundButton
        function Step2ForegroundButtonPushed(app, event)
            
            app.TippsTextArea.Value=["Now it's the Foreground process."
                      "" 
            "Click the point one by one around the Foreground on the figure."
                    "" 
            "When finished cutting, double click to confirm.  Then click the Step 3 Button."
            "" 
            "If you're not satisfied with result, right click the Step 2 Button to reset this step, fill the number and left click this Button to repeat again."];
           
            n = app.NumberofForegroundEditField.Value;
            patchsize = 9;
            fillorder = 'gradient';

            [fg2D, foreground, app.picture2] = SegmentImage(n, patchsize, fillorder,app.picture,app.UIAxes);
         
            imshow(app.picture2,'Parent',app.UIAxes);
            
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            n = app.NumberofForegroundEditField.Value;
            patchsize = 9;
            fillorder = 'gradient';
            [fg2D, foreground, app.picture2] = SegmentImage(n, patchsize, fillorder,app.picture,app.UIAxes);
         
            imshow(app.picture2,'Parent',app.UIAxes);

        end

        % Menu selected function: BeginnerMenu
        function BeginnerMenuSelected(app, event)
                    app.StepsPanel.Visible ="on";
                    app.TippsTextArea.Visible ="on";
                    app.TippsTextAreaLabel.Visible ="on";
                    app.Toolbar.Visible="off";
                    app.UIAxes.Position = [229 109 929 564];
        end

        % Menu selected function: ExpertMenu
        function ExpertMenuSelected(app, event)
                    app.StepsPanel.Visible="off";
                    app.TippsTextArea.Visible ="off";
                    app.TippsTextAreaLabel.Visible="off";
                    app.Toolbar.Visible="on";
                    app.UIAxes.Position = [17 61 1141 612];
        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
            app.NumberofForegroundLabel.Visible="off";
            app.NumberofForegroundEditField.Visible="off"; 
            app.points = Selection(app.picture,app.UIAxes);

        end

        % Button pushed function: Step43DModelButton
        function Step43DModelButtonPushed(app, event)
            app.TippsTextArea.Value=["Now it can show the 3D Model for this picture."
                      "" 
             ];
            cla(app.UIAxes);
            view(app.UIAxes,3); %3D version
            Build3DModel_new(app.points, app.picture,app.UIAxes);
        end

        % Callback function: PushTool4
        function PushTool4Clicked(app, event)
            cla(app.UIAxes);
            view(app.UIAxes,3); %3D version
            Build3DModel_new(app.points, app.picture,app.UIAxes);
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            %reset: load picture again
            cla(app.UIAxes);
        end

        % Context menu opening function: ContextMenu2
        function ContextMenu2Opening(app, event)
            cla(app.UIAxes);
            imshow(app.picture,'Parent',app.UIAxes);
           % delete
        end

        % Context menu opening function: ContextMenu3
        function ContextMenu3Opening(app, event)
            cla(app.UIAxes);
            if isempty(app.picture2)
                imshow(app.picture,'Parent',app.UIAxes);
            else
                imshow(app.picture2,'Parent',app.UIAxes);
            end
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            cla(app.UIAxes);
            app.picture2 =[];
            app.picture =[];
            view(app.UIAxes,2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'colorcube');
            app.UIFigure.Name = 'MATLAB App';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.UploadImageMenu = uimenu(app.ToolsMenu);
            app.UploadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @UploadImageMenuSelected, true);
            app.UploadImageMenu.Text = 'Upload Image';
            app.ScreenshotMenu = uimenu(app.ToolsMenu);
            app.ScreenshotMenu.Text = 'Screenshot';
            app.ModeSwitchMenu = uimenu(app.ToolsMenu);
            app.ModeSwitchMenu.Text = 'Mode Switch';
            app.BeginnerMenu = uimenu(app.ModeSwitchMenu);
            app.BeginnerMenu.MenuSelectedFcn = createCallbackFcn(app, @BeginnerMenuSelected, true);
            app.BeginnerMenu.Text = 'Beginner';
            app.ExpertMenu = uimenu(app.ModeSwitchMenu);
            app.ExpertMenu.MenuSelectedFcn = createCallbackFcn(app, @ExpertMenuSelected, true);
            app.ExpertMenu.Text = 'Expert';
            app.ResetMenu = uimenu(app.ToolsMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Toolbar.Visible = 'off';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'icon', 'upload.png');
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mat-wallpaper-0072bd.png');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-stateflow-chart-0072bd.png');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, 'icon', 'icon-fa-dropbox-0072bd.png');
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-media-video-0072bd.png');
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-photo-0072bd.png');
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.StepsPanel = uipanel(app.UIFigure);
            app.StepsPanel.Title = 'Steps';
            app.GridLayout = uigridlayout(app.StepsPanel);
            app.Step1LoadPictureButton = uibutton(app.GridLayout, 'push');
            app.Step1LoadPictureButton.ButtonPushedFcn = createCallbackFcn(app, @Step1LoadPictureButtonPushed, true);
            app.Step1LoadPictureButton.Icon = fullfile(pathToMLAPP, 'icon', 'uploadw.png');
            app.Step1LoadPictureButton.Text = {'Step 1: '; 'Load Picture'};
            app.Step2ForegroundButton = uibutton(app.GridLayout, 'push');
            app.Step2ForegroundButton.ButtonPushedFcn = createCallbackFcn(app, @Step2ForegroundButtonPushed, true);
            app.Step2ForegroundButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mat-wallpaper-ffffff-71.png');
            app.Step2ForegroundButton.Text = {'Step 2:'; 'Foreground'};
            app.Step3SelectionButton = uibutton(app.GridLayout, 'push');
            app.Step3SelectionButton.ButtonPushedFcn = createCallbackFcn(app, @Step3SelectionButtonPushed, true);
            app.Step3SelectionButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-stateflow-chart-ffffff-71.png');
            app.Step3SelectionButton.Text = {'Step 3:'; 'Selection'};
            app.Step43DModelButton = uibutton(app.GridLayout, 'push');
            app.Step43DModelButton.ButtonPushedFcn = createCallbackFcn(app, @Step43DModelButtonPushed, true);
            app.Step43DModelButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-fa-dropbox-ffffff-71.png');
            app.Step43DModelButton.Text = {'Step 4:'; '3D Model'};
            app.Step5AnimationButton_2 = uibutton(app.GridLayout, 'push');
            app.Step5AnimationButton_2.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-media-video-ffffff-71.png');
            app.Step5AnimationButton_2.Text = {'Step 5:'; 'Animation'};
            app.Step6ScreenshotButton = uibutton(app.GridLayout, 'push');
            app.Step6ScreenshotButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-photo-ffffff-71.png');
            app.Step6ScreenshotButton.Text = {'Step 6:'; 'Screenshot'};
            app.TippsTextAreaLabel = uilabel(app.UIFigure);
            app.TippsTextAreaLabel.Text = 'Tipps';
            app.TippsTextArea = uitextarea(app.UIFigure);
            app.TippsTextArea.Value = {'Welcome to the Tour Into the Picture!'; ''; 'Now read the GUI Instructions carefully and when finished click the OK Button.'; ''; 'Enjoy your Tour!'};
            app.ModeSwitchButton = uibutton(app.UIFigure, 'state');
            app.ModeSwitchButton.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchButtonValueChanged2, true);
            app.ModeSwitchButton.Text = 'Mode Switch';
            app.GUIInstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.GUIInstructionsTextAreaLabel.Text = {'GUI '; 'Instructions'};
            app.GUIInstructionsTextArea = uitextarea(app.UIFigure);
            app.GUIInstructionsTextArea.Value = {'This interface offers two modes: Beginner Mode (default) and Expert Mode. Switch modes using the button at the bottom.'; ''; '1.Beginner Mode'; 'Task Bar (Left): Follow the sequential tasks listed.'; ''; 'Image Display (Center): View related images for each task.'; ''; 'Tip Area (Bottom): Get step-by-step guidance and tipps.'; ''; ''; '2.Expert Mode'; 'Icon Toolbar (Below Menu Bar): Use the tools in a left-to-right sequence.'; ''; 'Designed for experienced users with a streamlined layout.'; ''; ''; '3.Menu Bar (Top)'; 'Contains commonly used tools, including a reset tool that restart all step from beginning or switching images.'; ''; ''; '4.Mode Switching'; 'Use the bottom button to switch between Beginner Mode and Expert Mode.'; ''; ''; ''; 'Enjoy using the GUI! '};
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.NumberofForegroundLabel = uilabel(app.UIFigure);
            app.NumberofForegroundLabel.Visible = 'off';
            app.NumberofForegroundLabel.Text = 'Number of Foreground:';
            app.NumberofForegroundEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofForegroundEditField.Visible = 'off';
            app.NumberofForegroundEditField.Value = 1;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.ResetMenu_2 = uimenu(app.ContextMenu);
            app.ResetMenu_2.Text = 'Reset';
            % Assign app.ContextMenu
            app.Step1LoadPictureButton.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.ContextMenu2.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu2Opening, true);
            app.ResetMenu_3 = uimenu(app.ContextMenu2);
            app.ResetMenu_3.Text = 'Reset';
            % Assign app.ContextMenu2
            app.Step2ForegroundButton.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.ContextMenu3.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu3Opening, true);
            app.ResetMenu_4 = uimenu(app.ContextMenu3);
            app.ResetMenu_4.Text = 'Reset';
            % Assign app.ContextMenu3
            app.Step3SelectionButton.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end